classdef PipetteForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        NoofElectrodesEditFieldLabel    matlab.ui.control.Label
        CompleteCheckBox                matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        CreateButton                    matlab.ui.control.Button
        MaterialEditField               matlab.ui.control.EditField
        MaterialEditFieldLabel          matlab.ui.control.Label
        ManufacturerEditField           matlab.ui.control.EditField
        ManufaturerEditFieldLabel       matlab.ui.control.Label
        DigitalIdentifierEditField      matlab.ui.control.EditField
        DigitalIdentifierEditFieldLabel  matlab.ui.control.Label
        DescriptionEditField            matlab.ui.control.EditField
        DescriptionEditFieldLabel       matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ExternalDiameterEditField       matlab.ui.control.EditField
        ExternalDiameterEditFieldLabel  matlab.ui.control.Label
        InternalDiameterEditField       matlab.ui.control.EditField
        InternalDiameterEditFieldLabel  matlab.ui.control.Label
        PIPETTELabel                    matlab.ui.control.Label
    end

         
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end

    methods (Access = public)
    
        function setProbeDetails(app, pipette)
            app.NameEditField.Value = pipette.Name;
            app.DeviceTypeEditField.Value = pipette.DeviceType;
            app.DescriptionEditField.Value = pipette.Description;
            app.DigitalIdentifierEditField.Value = pipette.DigitalIdentifier;
            app.ManufacturerEditField.Value = pipette.Manufacturer;
            app.CompleteCheckBox.Value = pipette.Complete;
            app.ExternalDiameterEditField.Value = pipette.ExternalDiameter;
            app.InternalDiameterEditField.Value = pipette.InternalDiameter;
            app.MaterialEditField.Value = pipette.Material;
        end

        function pipette = getProbeDetails(app)
            pipette = ndi.database.metadata_app.class.Pipette();
            pipette.updateProperty("Name", app.NameEditField.Value);
            pipette.updateProperty("DeviceType", app.DeviceTypeEditField.Value);
            pipette.updateProperty("Description", app.DescriptionEditField.Value);
            pipette.updateProperty("DigitalIdentifier", app.DigitalIdentifierEditField.Value);
            pipette.updateProperty("Manufacturer", app.ManufacturerEditField.Value);
            pipette.updateProperty("Complete", app.CompleteCheckBox.Value);
            pipette.updateProperty("ExternalDiameter", app.ExternalDiameterEditField.Value);
            pipette.updateProperty("InternalDiameter", app.InternalDiameterEditField.Value);
            pipette.updateProperty("Material", app.MaterialEditField.Value);
        end

        function reset(app)
            app.NameEditField.Value = '';
            app.DeviceTypeEditField.Value = '';
            app.DescriptionEditField.Value = '';
            app.DigitalIdentifierEditField.Value = '';
            app.ManufacturerEditField.Value = '';
            app.CompleteCheckBox.Value = 0;
            app.ExternalDiameterEditField.Value = '';
            app.InternalDiameterEditField.Value = '';
            app.MaterialEditField.Value = '';
            app.FinishState = "";
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest()
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.FinishState = "Save";
            app.UIFigureCloseRequest()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save the new electrode array?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.PIPETTELabel = uilabel(app.UIFigure);
            app.PIPETTELabel.Text = 'PIPETTE';
            app.InternalDiameterEditFieldLabel = uilabel(app.UIFigure);
            app.InternalDiameterEditFieldLabel.Text = 'Internal Diameter';
            app.InternalDiameterEditField = uieditfield(app.UIFigure, 'text');
            app.InternalDiameterEditField.Tooltip = {'Optional'};
            app.ExternalDiameterEditFieldLabel = uilabel(app.UIFigure);
            app.ExternalDiameterEditFieldLabel.Text = 'External Diameter';
            app.ExternalDiameterEditField = uieditfield(app.UIFigure, 'text');
            app.ExternalDiameterEditField.Tooltip = {'Optional'};
            app.NameEditFieldLabel = uilabel(app.UIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.UIFigure, 'text');
            app.NameEditField.Editable = 'off';
            app.DescriptionEditFieldLabel = uilabel(app.UIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.UIFigure, 'text');
            app.DescriptionEditField.Placeholder = 'Catalog No.';
            app.DigitalIdentifierEditFieldLabel = uilabel(app.UIFigure);
            app.DigitalIdentifierEditFieldLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditField = uieditfield(app.UIFigure, 'text');
            app.DigitalIdentifierEditField.Placeholder = 'DOI/RRID';
            app.ManufaturerEditFieldLabel = uilabel(app.UIFigure);
            app.ManufaturerEditFieldLabel.Text = 'Manufaturer';
            app.ManufacturerEditField = uieditfield(app.UIFigure, 'text');
            app.MaterialEditFieldLabel = uilabel(app.UIFigure);
            app.MaterialEditFieldLabel.Text = 'Material';
            app.MaterialEditField = uieditfield(app.UIFigure, 'text');
            app.CreateButton = uibutton(app.UIFigure, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CompleteCheckBox = uicheckbox(app.UIFigure);
            app.CompleteCheckBox.Text = '';
            app.NoofElectrodesEditFieldLabel = uilabel(app.UIFigure);
            app.NoofElectrodesEditFieldLabel.Text = 'Complete';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PipetteForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end